classdef manual_thinning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        topFreqEditField           matlab.ui.control.NumericEditField
        topFreqEditFieldLabel      matlab.ui.control.Label
        initialFreqEditField       matlab.ui.control.NumericEditField
        initialFreqEditFieldLabel  matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        AF_1Tab                    matlab.ui.container.Tab
        AF1Axes                    matlab.ui.control.UIAxes
        AF_2Tab                    matlab.ui.container.Tab
        AF2Axes                    matlab.ui.control.UIAxes
        BandwidthTab               matlab.ui.container.Tab
        BWAxes                     matlab.ui.control.UIAxes
        UITable                    matlab.ui.control.Table
        ExportButton               matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
            Arraydata struct
            thinnedArray struct
    end


    methods (Access = private)
        
        % Code that executes after component creation
        function startupFcn(app)
            % Initialize Arraydata
            app.Arraydata.positions = []; % Example field
            app.Arraydata.excitations = []; % Example field
        
            % Initialize thinnedArray similarly
            app.thinnedArray.positions = [];
            app.thinnedArray.excitations = [];
        end


            % Callback for toggling element state
     function toggleElement(app, src)
            % Directly use 'src' which is the object that triggered the event
            idx = src.UserData;
        
            % Toggle the state in thinnedArray
            app.thinnedArray.excitations(idx) = ~app.thinnedArray.excitations(idx);
        
            % Update the GUI based on the new state
            if app.thinnedArray.excitations(idx)
                src.MarkerFaceColor = 'b';  % On state, Blue
                src.MarkerEdgeColor = 'b';
            else
                src.MarkerFaceColor = 'r';  % Off state, Red
                src.MarkerEdgeColor = 'r';
            end
        
            % Update evaluations and plots if necessary
           updates(app)
     end


    function updateUI(app)
    % Update plots or other UI components
    % For example, re-plot positions with new data
    if isfield(app.Arraydata, 'positions') && isfield(app.Arraydata, 'excitations')
        plotArray(app);
    end
    end


    function updates(app)
        % Define the angle for plotting
        d1= app.initialFreqEditField.Value;
        d2= app.topFreqEditField.Value;
        angle = linspace(0, pi, 720);
    
        % Calculate the Array Factor for both the original and the thinned arrays
        AF_original = evaluate(app.Arraydata, length(angle), d1);
        AF_thinned = evaluate(app.thinnedArray, length(angle), d1);
    
        % Clear current axes and redraw plots
        cla(app.AF1Axes); % Clear the axes for fresh plotting
        hold(app.AF1Axes, 'on');
        plot(app.AF1Axes, angle, mag2db(AF_original), 'LineWidth', 1.5, 'Color', 'b');
        plot(app.AF1Axes, angle, mag2db(AF_thinned), 'LineWidth', 1.5, 'Color', 'r');
        hold(app.AF1Axes, 'off');
    
        % Set the axes properties directly within this function
        xlim(app.AF1Axes, [0 pi]);
        ylim(app.AF1Axes, [-60 0]);
        xticks(app.AF1Axes, [0 pi/4 pi/2 3*pi/4 pi]);
        xticklabels(app.AF1Axes, {'0', '\pi/4', '\pi/2', '3\pi/4', '\pi'});
        grid(app.AF1Axes, 'on');
        legend(app.AF1Axes, 'Original', 'Thinned');
        
        IDs = 1:1:length(app.Arraydata.excitations);
        app.UITable.Data = [IDs' ,app.thinnedArray.excitations']

        %%
        
        AF_original = evaluate(app.Arraydata, length(angle), d2);
        AF_thinned = evaluate(app.thinnedArray, length(angle), d2);

        cla(app.AF2Axes); % Clear the axes for fresh plotting
        hold(app.AF2Axes, 'on');
        plot(app.AF2Axes, angle, mag2db(AF_original), 'LineWidth', 1.5, 'Color', 'b');
        plot(app.AF2Axes, angle, mag2db(AF_thinned), 'LineWidth', 1.5, 'Color', 'r');
        hold(app.AF2Axes, 'off');
    
        % Set the axes properties directly within this function
        xlim(app.AF2Axes, [0 pi]);
        ylim(app.AF2Axes, [-60 0]);
        xticks(app.AF2Axes, [0 pi/4 pi/2 3*pi/4 pi]);
        xticklabels(app.AF2Axes, {'0', '\pi/4', '\pi/2', '3\pi/4', '\pi'});
        grid(app.AF2Axes, 'on');
        legend(app.AF2Axes, 'Original', 'Thinned');

        %%Bandwidth UPDATE
        range = 0.5:0.2:6;
        [SLLs_1,bandwidth1] = calculateSpectrum2(app.Arraydata,range);
        [SLLs_2,bandwidth2] = calculateSpectrum2(app.thinnedArray,range);

        cla(app.BWAxes); % Clear the axes for fresh plotting
        hold(app.BWAxes, 'on');
        plot(app.BWAxes,range,SLLs_1, 'LineWidth', 1.5, 'Color','b');
        plot(app.BWAxes,range,SLLs_2, 'LineWidth', 1.5, 'Color', 'r');
        hold(app.BWAxes, 'off');
        %legend(app.BWAxes,bandwidth1,bandwidth2)
        
    end

function plotArray(app)
    % Clear existing plots
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');

    % Plot each position
    for idx = 1:length(app.Arraydata.positions)
        color = 'r';
        if app.Arraydata.excitations(idx) == 1
            color = 'b';
        end
        plot(app.UIAxes, real(app.Arraydata.positions(idx)), imag(app.Arraydata.positions(idx)), ...
             'o', 'MarkerSize', 10, 'MarkerFaceColor', color, 'MarkerEdgeColor', color, ...
             'UserData', idx, 'ButtonDownFcn', @(src, event) toggleElement(app, src));
    end
    hold(app.UIAxes, 'off');
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
             % Open file dialog to select MAT file
    [file, path] = uigetfile('*.mat', 'Select a MAT file with Arraydata');
    if isequal(file, 0)
        disp('User selected Cancel');
    else
        % Load the selected file
        loadedData = load(fullfile(path, file));
        if isfield(loadedData, 'Arraydata')
            app.Arraydata = loadedData.Arraydata;
            % Update UI or plots as necessary
            updateUI(app);
        else
            uialert(app.UIFigure, 'The selected file does not contain Arraydata', 'File Error');
        end

            % Initialize thinnedArray similarly
            app.thinnedArray =app.Arraydata;
            % Update evaluations and plots if necessary
           updates(app)
    end

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
               % Open file dialog to specify the path for saving
            [file, path] = uiputfile('*.mat', 'Save thinnedArray As');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % Save the thinnedArray to the specified file
                thinnedArray = app.thinnedArray; %#ok<ADPROPLC> % Temporarily store to variable for saving
                save(fullfile(path, file), 'thinnedArray');
            end
        end

        % Button down function: BandwidthTab
        function BandwidthTabButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Click to toggle elements off/on')
            xlabel(app.UIAxes, 'Array structure')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Element ID'; 'Thinning Matrix'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AF_1Tab = uitab(app.TabGroup);
            app.AF_1Tab.Title = 'AF_1';
            app.AF1Axes = uiaxes(app.AF_1Tab);
            title(app.AF1Axes, 'Initial Freq response')
            xlabel(app.AF1Axes, 'θ(rads)')
            ylabel(app.AF1Axes, 'AF(dB)')
            zlabel(app.AF1Axes, 'Z')
            app.AF_2Tab = uitab(app.TabGroup);
            app.AF_2Tab.Title = 'AF_2';
            app.AF2Axes = uiaxes(app.AF_2Tab);
            title(app.AF2Axes, 'Top Freq response')
            xlabel(app.AF2Axes, 'θ(rads)')
            ylabel(app.AF2Axes, 'AF(dB)')
            zlabel(app.AF2Axes, 'Z')
            app.BandwidthTab = uitab(app.TabGroup);
            app.BandwidthTab.Title = 'Bandwidth';
            app.BandwidthTab.ButtonDownFcn = createCallbackFcn(app, @BandwidthTabButtonDown, true);
            app.BWAxes = uiaxes(app.BandwidthTab);
            title(app.BWAxes, 'Bandwith Response')
            xlabel(app.BWAxes, 'd/λ')
            ylabel(app.BWAxes, 'Peak SLL(dB)')
            zlabel(app.BWAxes, 'Z')
            app.BWAxes.XLim = [0.5 4];
            app.BWAxes.YLim = [-60 0];
            app.BWAxes.XTick = [0.5 1 1.5 2 2.5 3 3.5 4];
            app.BWAxes.XTickLabel = {'0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.5'; '4'};
            app.initialFreqEditFieldLabel = uilabel(app.UIFigure);
            app.initialFreqEditFieldLabel.Text = 'initialFreq';
            app.initialFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.initialFreqEditField.Limits = [0.5 10];
            app.initialFreqEditField.Value = 0.5;
            app.topFreqEditFieldLabel = uilabel(app.UIFigure);
            app.topFreqEditFieldLabel.Text = 'topFreq';
            app.topFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.topFreqEditField.Limits = [0.5 10];
            app.topFreqEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_thinning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end