classdef structure_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ExportButton          matlab.ui.control.Button
        DataTable             matlab.ui.control.Table
        TabGroup              matlab.ui.container.TabGroup
        PlanarTab             matlab.ui.container.Tab
        LatticeDropDown       matlab.ui.control.DropDown
        LatticeDropDownLabel  matlab.ui.control.Label
        dEditField            matlab.ui.control.NumericEditField
        dEditFieldLabel       matlab.ui.control.Label
        MEditField            matlab.ui.control.NumericEditField
        MEditFieldLabel       matlab.ui.control.Label
        NEditField            matlab.ui.control.NumericEditField
        NEditFieldLabel       matlab.ui.control.Label
        generateButton        matlab.ui.control.Button
        RingsTab              matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Arraydata = struct('d', [], 'N', [], 'positions', [], 'excitations', []);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    if ispc  % For Windows
        folderPath = '\functions';
    elseif isunix  % For Linux and macOS
        folderPath = '/functions';
    end
    addpath(folderPath);
        end

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
    if ispc  % For Windows
        folderPath = '\functions';
    elseif isunix  % For Linux and macOS
        folderPath = '/functions';
    end
    addpath(folderPath);

    N = app.NEditField.Value;
    M = app.MEditField.Value;
    d = app.dEditField.Value;
    latticeType = app.LatticeDropDown.Value;


    if strcmp(latticeType, 'Triangular')
        positions = get_eisenstein_positions(N, M, d);
    else
        positions = get_positions(N, M, d);
    end

        positions = reshape(positions, [], 1);
    % Update the app-wide Arraydata structure
    app.Arraydata.d = d;
    app.Arraydata.N = N * M;
    app.Arraydata.positions = positions;
    app.Arraydata.excitations = ones(1, length(positions));


    % Plotting the array structure
    scatter(app.UIAxes, real(positions), imag(positions), 'filled');
    axis(app.UIAxes, 'image');
    title(app.UIAxes, 'Array Structure');
    grid(app.UIAxes, 'on');


    % Convert the structure to a table
    dataArrayTable = struct2table(app.Arraydata);
        % Assign the table to the UITable component
    app.UITable.Data = dataArrayTable;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
 % Retrieve the full structure to a local variable
    Arraydata = app.Arraydata;
    % Get the directory where the app's .mlapp file is located
    [currentDir, ~, ~] = fileparts(mfilename('fullpath'));

    % Setting a default filename and constructing the full path for uiputfile
    defaultFileName = fullfile(currentDir, 'ArrayData.mat');

    % Open the save file dialog at the current directory with the default filename
    [file, path] = uiputfile('*.mat', 'Save Array Data As', defaultFileName);
    if isequal(file, 0) || isequal(path, 0)
        disp('User canceled export.');
    else
        fullFileName = fullfile(path, file);
        % Save the structure to a .mat file using the local variable
        save(fullFileName, 'Arraydata');
        disp(['Data exported successfully to ', fullFileName]);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Array Structure')
            xlabel(app.UIAxes, '(d/λ)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlanarTab = uitab(app.TabGroup);
            app.PlanarTab.Title = 'Planar';
            app.generateButton = uibutton(app.PlanarTab, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'generate';
            app.NEditFieldLabel = uilabel(app.PlanarTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.PlanarTab, 'numeric');
            app.NEditField.Limits = [0 100];
            app.NEditField.RoundFractionalValues = 'on';
            app.NEditField.Value = 10;
            app.MEditFieldLabel = uilabel(app.PlanarTab);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.PlanarTab, 'numeric');
            app.MEditField.Limits = [0 100];
            app.MEditField.RoundFractionalValues = 'on';
            app.MEditField.Value = 10;
            app.dEditFieldLabel = uilabel(app.PlanarTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.PlanarTab, 'numeric');
            app.dEditField.Limits = [0.5 10];
            app.dEditField.Value = 1;
            app.LatticeDropDownLabel = uilabel(app.PlanarTab);
            app.LatticeDropDownLabel.Text = 'Lattice';
            app.LatticeDropDown = uidropdown(app.PlanarTab);
            app.LatticeDropDown.Items = {'Rectangular', 'Triangular'};
            app.LatticeDropDown.Value = 'Rectangular';
            app.RingsTab = uitab(app.TabGroup);
            app.RingsTab.Title = 'Rings';
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DataTable.RowName = {};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = structure_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end